classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        NextButton             matlab.ui.control.Button
        Stock1EditFieldLabel   matlab.ui.control.Label
        Stock1EditField        matlab.ui.control.EditField
        Stock2EditFieldLabel   matlab.ui.control.Label
        Stock2EditField        matlab.ui.control.EditField
        Stock3EditFieldLabel   matlab.ui.control.Label
        Stock3EditField        matlab.ui.control.EditField
        Stock4EditFieldLabel   matlab.ui.control.Label
        Stock4EditField        matlab.ui.control.EditField
        Stock5EditFieldLabel   matlab.ui.control.Label
        Stock5EditField        matlab.ui.control.EditField
        Stock6EditFieldLabel   matlab.ui.control.Label
        Stock6EditField        matlab.ui.control.EditField
        Stock7EditFieldLabel   matlab.ui.control.Label
        Stock7EditField        matlab.ui.control.EditField
        Stock8EditFieldLabel   matlab.ui.control.Label
        Stock8EditField        matlab.ui.control.EditField
        Stock9EditFieldLabel   matlab.ui.control.Label
        Stock9EditField        matlab.ui.control.EditField
        Stock10EditFieldLabel  matlab.ui.control.Label
        Stock10EditField       matlab.ui.control.EditField
        TradeButton            matlab.ui.control.Button
        BacktestingButton      matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        ClearButton            matlab.ui.control.Button
        BackButton             matlab.ui.control.Button
        Stock11EditFieldLabel  matlab.ui.control.Label
        Stock11EditField       matlab.ui.control.EditField
        Stock12EditFieldLabel  matlab.ui.control.Label
        Stock12EditField       matlab.ui.control.EditField
        Stock13EditFieldLabel  matlab.ui.control.Label
        Stock13EditField       matlab.ui.control.EditField
        Stock14EditFieldLabel  matlab.ui.control.Label
        Stock14EditField       matlab.ui.control.EditField
        Stock15EditFieldLabel  matlab.ui.control.Label
        Stock15EditField       matlab.ui.control.EditField
        Stock16EditFieldLabel  matlab.ui.control.Label
        Stock16EditField       matlab.ui.control.EditField
        Stock17EditFieldLabel  matlab.ui.control.Label
        Stock17EditField       matlab.ui.control.EditField
        Stock18EditFieldLabel  matlab.ui.control.Label
        Stock18EditField       matlab.ui.control.EditField
        Stock19EditFieldLabel  matlab.ui.control.Label
        Stock19EditField       matlab.ui.control.EditField
        Stock20EditFieldLabel  matlab.ui.control.Label
        Stock20EditField       matlab.ui.control.EditField
        Panel                  matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        STOPButton             matlab.ui.control.Button
        PROFITButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            switch (app.NextButton.Text)
                case 'Next'
                    app.NextButton.Text = 'Reset';
                    app.Stock1EditField.Visible = 'off';
                    app.Stock2EditField.Visible = 'off';
                    app.Stock3EditField.Visible = 'off';
                    app.Stock4EditField.Visible = 'off';
                    app.Stock5EditField.Visible = 'off';
                    app.Stock6EditField.Visible = 'off';
                    app.Stock7EditField.Visible = 'off';
                    app.Stock8EditField.Visible = 'off';
                    app.Stock9EditField.Visible = 'off';
                    app.Stock10EditField.Visible = 'off';
                    app.Stock11EditField.Visible = 'off';
                    app.Stock12EditField.Visible = 'off';
                    app.Stock13EditField.Visible = 'off';
                    app.Stock14EditField.Visible = 'off';
                    app.Stock15EditField.Visible = 'off';
                    app.Stock16EditField.Visible = 'off';
                    app.Stock17EditField.Visible = 'off';
                    app.Stock18EditField.Visible = 'off';
                    app.Stock19EditField.Visible = 'off';
                    app.Stock20EditField.Visible = 'off';
                    app.Stock1EditFieldLabel.Visible = 'off';
                    app.Stock2EditFieldLabel.Visible = 'off';
                    app.Stock3EditFieldLabel.Visible = 'off';
                    app.Stock4EditFieldLabel.Visible = 'off';
                    app.Stock5EditFieldLabel.Visible = 'off';
                    app.Stock6EditFieldLabel.Visible = 'off';
                    app.Stock7EditFieldLabel.Visible = 'off';
                    app.Stock8EditFieldLabel.Visible = 'off';
                    app.Stock9EditFieldLabel.Visible = 'off';
                    app.Stock10EditFieldLabel.Visible = 'off';
                    app.Stock11EditFieldLabel.Visible = 'off';
                    app.Stock12EditFieldLabel.Visible = 'off';
                    app.Stock13EditFieldLabel.Visible = 'off';
                    app.Stock14EditFieldLabel.Visible = 'off';
                    app.Stock15EditFieldLabel.Visible = 'off';
                    app.Stock16EditFieldLabel.Visible = 'off';
                    app.Stock17EditFieldLabel.Visible = 'off';
                    app.Stock18EditFieldLabel.Visible = 'off';
                    app.Stock19EditFieldLabel.Visible = 'off';
                    app.Stock20EditFieldLabel.Visible = 'off';
                    app.Panel.Visible = 'off';
                    app.TradeButton.Visible = 'on';
                    app.BacktestingButton.Visible = 'on';
                    
                    app.UITable.Visible = 'off';
                    
                    app.ClearButton.Visible = 'off';
                    app.BackButton.Visible = 'off';
                    app.PROFITButton.Visible='on';


                case 'Reset'
                    app.NextButton.Text = 'Next';
                    app.Stock1EditField.Visible = 'on';
                    app.Stock2EditField.Visible = 'on';
                    app.Stock3EditField.Visible = 'on';
                    app.Stock4EditField.Visible = 'on';
                    app.Stock5EditField.Visible = 'on';
                    app.Stock6EditField.Visible = 'on';
                    app.Stock7EditField.Visible = 'on';
                    app.Stock8EditField.Visible = 'on';
                    app.Stock9EditField.Visible = 'on';
                    app.Stock10EditField.Visible = 'on';
                    app.Stock11EditField.Visible = 'on';
                    app.Stock12EditField.Visible = 'on';
                    app.Stock13EditField.Visible = 'on';
                    app.Stock14EditField.Visible = 'on';
                    app.Stock15EditField.Visible = 'on';
                    app.Stock16EditField.Visible = 'on';
                    app.Stock17EditField.Visible = 'on';
                    app.Stock18EditField.Visible = 'on';
                    app.Stock19EditField.Visible = 'on';
                    app.Stock20EditField.Visible = 'on';
                    app.Stock1EditFieldLabel.Visible = 'on';
                    app.Stock2EditFieldLabel.Visible = 'on';
                    app.Stock3EditFieldLabel.Visible = 'on';
                    app.Stock4EditFieldLabel.Visible = 'on';
                    app.Stock5EditFieldLabel.Visible = 'on';
                    app.Stock6EditFieldLabel.Visible = 'on';
                    app.Stock7EditFieldLabel.Visible = 'on';
                    app.Stock8EditFieldLabel.Visible = 'on';
                    app.Stock9EditFieldLabel.Visible = 'on';
                    app.Stock10EditFieldLabel.Visible = 'on';
                    app.Stock11EditFieldLabel.Visible = 'on';
                    app.Stock12EditFieldLabel.Visible = 'on';
                    app.Stock13EditFieldLabel.Visible = 'on';
                    app.Stock14EditFieldLabel.Visible = 'on';
                    app.Stock15EditFieldLabel.Visible = 'on';
                    app.Stock16EditFieldLabel.Visible = 'on';
                    app.Stock17EditFieldLabel.Visible = 'on';
                    app.Stock18EditFieldLabel.Visible = 'on';
                    app.Stock19EditFieldLabel.Visible = 'on';
                    app.Stock20EditFieldLabel.Visible = 'on';
                    
                    
                    app.TradeButton.Visible = 'off';
                    app.BacktestingButton.Visible = 'off';
                    app.Panel.Visible = 'off';
                    app.UITable.Visible = 'off';
                    app.PROFITButton.Visible='off';
                   
                    app.ClearButton.Visible = 'on';
                    app.BackButton.Visible = 'off';


            end
            
        end

        % Button pushed function: TradeButton
        function TradeButtonPushed(app, event)
            app.Panel.Visible = 'off';
            app.UITable.Visible = 'on';
            app.BacktestingButton.Visible ='off';
            app.TradeButton.Visible = 'off';
            app.PROFITButton.Visible='off';
            app.BackButton.Visible='on';
            testtonight2;
            
            
        end

        % Button pushed function: BacktestingButton
        function BacktestingButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.UITable.Visible = 'off';
            app.BacktestingButton.Visible = 'off';
            app.TradeButton.Visible = 'off';
            app.BackButton.Visible = 'on';
            y=(1+get_return(app.Stock18EditField.Value)).*(1+get_return(app.Stock19EditField.Value));
            x=1:1:length(y);
            plot(app.UIAxes,x,y);
            app.PROFITButton.Visible='off';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.Stock1EditField.Value = '';
            app.Stock2EditField.Value = '';
            app.Stock3EditField.Value = '';
            app.Stock4EditField.Value = '';
            app.Stock5EditField.Value = '';
            app.Stock6EditField.Value = '';
            app.Stock7EditField.Value = '';
            app.Stock8EditField.Value = '';
            app.Stock9EditField.Value = '';
            app.Stock10EditField.Value = '';
            app.Stock10EditField.Value = '';
            app.Stock11EditField.Value = '';
            app.Stock12EditField.Value = '';
            app.Stock13EditField.Value = '';
            app.Stock14EditField.Value = '';
            app.Stock15EditField.Value = '';
            app.Stock16EditField.Value = '';
            app.Stock17EditField.Value = '';
            app.Stock18EditField.Value = '';
            app.Stock19EditField.Value = '';
            app.Stock20EditField.Value = '';


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.UITable.Visible = 'off';
            
            app.TradeButton.Visible = 'on';
            app.BacktestingButton.Visible = 'on';
            app.BackButton.Visible = 'off';
            app.Panel.Visible = 'off';
            app.PROFITButton.Visible='off';
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            delete(timerfind);
            IBMatlab('action','CANCEL');
        end

        % Button pushed function: PROFITButton
        function PROFITButtonPushed(app, event)
            app.UITable.Visible = 'on';
            for i=1:1000
                pause(2)
                data = IBMatlab('action','portfolio');
                data= struct2table(data);
                app.UITable.Data=table(data.symbol,data.unrealizedPnL);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Stock1EditFieldLabel = uilabel(app.UIFigure);
            app.Stock1EditFieldLabel.Text = 'Stock 1';
            app.Stock1EditField = uieditfield(app.UIFigure, 'text');
            app.Stock2EditFieldLabel = uilabel(app.UIFigure);
            app.Stock2EditFieldLabel.Text = 'Stock 2';
            app.Stock2EditField = uieditfield(app.UIFigure, 'text');
            app.Stock3EditFieldLabel = uilabel(app.UIFigure);
            app.Stock3EditFieldLabel.Text = 'Stock 3';
            app.Stock3EditField = uieditfield(app.UIFigure, 'text');
            app.Stock4EditFieldLabel = uilabel(app.UIFigure);
            app.Stock4EditFieldLabel.Text = 'Stock 4';
            app.Stock4EditField = uieditfield(app.UIFigure, 'text');
            app.Stock5EditFieldLabel = uilabel(app.UIFigure);
            app.Stock5EditFieldLabel.Text = 'Stock 5';
            app.Stock5EditField = uieditfield(app.UIFigure, 'text');
            app.Stock6EditFieldLabel = uilabel(app.UIFigure);
            app.Stock6EditFieldLabel.Text = 'Stock 6';
            app.Stock6EditField = uieditfield(app.UIFigure, 'text');
            app.Stock7EditFieldLabel = uilabel(app.UIFigure);
            app.Stock7EditFieldLabel.Text = 'Stock 7';
            app.Stock7EditField = uieditfield(app.UIFigure, 'text');
            app.Stock8EditFieldLabel = uilabel(app.UIFigure);
            app.Stock8EditFieldLabel.Text = 'Stock 8';
            app.Stock8EditField = uieditfield(app.UIFigure, 'text');
            app.Stock9EditFieldLabel = uilabel(app.UIFigure);
            app.Stock9EditFieldLabel.Text = 'Stock 9';
            app.Stock9EditField = uieditfield(app.UIFigure, 'text');
            app.Stock10EditFieldLabel = uilabel(app.UIFigure);
            app.Stock10EditFieldLabel.Text = 'Stock 10';
            app.Stock10EditField = uieditfield(app.UIFigure, 'text');
            app.TradeButton = uibutton(app.UIFigure, 'push');
            app.TradeButton.ButtonPushedFcn = createCallbackFcn(app, @TradeButtonPushed, true);
            app.TradeButton.Text = 'Trade';
            app.BacktestingButton = uibutton(app.UIFigure, 'push');
            app.BacktestingButton.ButtonPushedFcn = createCallbackFcn(app, @BacktestingButtonPushed, true);
            app.BacktestingButton.Text = 'Backtesting';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Ticker'; 'Profit'; ''; 'Column 4'};
            app.UITable.RowName = {};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Visible = 'off';
            app.BackButton.Text = 'Back';
            app.Stock11EditFieldLabel = uilabel(app.UIFigure);
            app.Stock11EditFieldLabel.Text = 'Stock 11';
            app.Stock11EditField = uieditfield(app.UIFigure, 'text');
            app.Stock12EditFieldLabel = uilabel(app.UIFigure);
            app.Stock12EditFieldLabel.Text = 'Stock 12';
            app.Stock12EditField = uieditfield(app.UIFigure, 'text');
            app.Stock13EditFieldLabel = uilabel(app.UIFigure);
            app.Stock13EditFieldLabel.Text = 'Stock 13';
            app.Stock13EditField = uieditfield(app.UIFigure, 'text');
            app.Stock14EditFieldLabel = uilabel(app.UIFigure);
            app.Stock14EditFieldLabel.Text = 'Stock 14';
            app.Stock14EditField = uieditfield(app.UIFigure, 'text');
            app.Stock15EditFieldLabel = uilabel(app.UIFigure);
            app.Stock15EditFieldLabel.Text = 'Stock 15';
            app.Stock15EditField = uieditfield(app.UIFigure, 'text');
            app.Stock16EditFieldLabel = uilabel(app.UIFigure);
            app.Stock16EditFieldLabel.Text = 'Stock 16';
            app.Stock16EditField = uieditfield(app.UIFigure, 'text');
            app.Stock17EditFieldLabel = uilabel(app.UIFigure);
            app.Stock17EditFieldLabel.Text = 'Stock 17';
            app.Stock17EditField = uieditfield(app.UIFigure, 'text');
            app.Stock18EditFieldLabel = uilabel(app.UIFigure);
            app.Stock18EditFieldLabel.Text = 'Stock 18';
            app.Stock18EditField = uieditfield(app.UIFigure, 'text');
            app.Stock19EditFieldLabel = uilabel(app.UIFigure);
            app.Stock19EditFieldLabel.Text = 'Stock 19';
            app.Stock19EditField = uieditfield(app.UIFigure, 'text');
            app.Stock20EditFieldLabel = uilabel(app.UIFigure);
            app.Stock20EditFieldLabel.Text = 'Stock 20';
            app.Stock20EditField = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Backtesting for portfolio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP!';
            app.PROFITButton = uibutton(app.UIFigure, 'push');
            app.PROFITButton.ButtonPushedFcn = createCallbackFcn(app, @PROFITButtonPushed, true);
            app.PROFITButton.Text = 'PROFIT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end